classdef KymoAnnotate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        KymographAnalysisToolLabel   matlab.ui.control.Label
        InitializePanel              matlab.ui.container.Panel
        SelectKymographButton        matlab.ui.control.Button
        StartFrameEditFieldLabel     matlab.ui.control.Label
        StartFrameEditField          matlab.ui.control.NumericEditField
        TimeStepsEditField_4Label    matlab.ui.control.Label
        TimeStepsEditField           matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel       matlab.ui.control.Label
        EndFrameEditField            matlab.ui.control.NumericEditField
        StartTimesEditFieldLabel     matlab.ui.control.Label
        StartTimesEditField          matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel       matlab.ui.control.Label
        EndTimesEditField            matlab.ui.control.NumericEditField
        SpindleTypeButtonGroup       matlab.ui.container.ButtonGroup
        MonopolarButton              matlab.ui.control.RadioButton
        BipolarButton                matlab.ui.control.RadioButton
        CommentsTextAreaLabel        matlab.ui.control.Label
        CommentsTextArea             matlab.ui.control.TextArea
        XPixelSizeumEditFieldLabel   matlab.ui.control.Label
        XPixelSizeumEditField        matlab.ui.control.NumericEditField
        AddSPBLineButton             matlab.ui.control.Button
        AddSPBCurveButton            matlab.ui.control.Button
        ProcessPanel                 matlab.ui.container.Panel
        LineDirectionButtonGroup     matlab.ui.container.ButtonGroup
        PolewardButton               matlab.ui.control.RadioButton
        AntipolewardButton           matlab.ui.control.RadioButton
        AmbiguousButton              matlab.ui.control.RadioButton
        FinalizeLineButton           matlab.ui.control.Button
        DeleteLineButton             matlab.ui.control.Button
        SaveFeaturesButton           matlab.ui.control.Button
        SelectFeatureButtonGroup     matlab.ui.container.ButtonGroup
        LineButton                   matlab.ui.control.ToggleButton
        CurveButton                  matlab.ui.control.ToggleButton
        DrawButton                   matlab.ui.control.Button
        ParticleVelocityButtonGroup  matlab.ui.container.ButtonGroup
        SlowButton                   matlab.ui.control.RadioButton
        FastButton                   matlab.ui.control.RadioButton
        Button                       matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        FinalizeAllButton            matlab.ui.control.Button
        ImageAdjustmentPanel         matlab.ui.container.Panel
        MinSliderLabel               matlab.ui.control.Label
        MinSlider                    matlab.ui.control.Slider
        MaxSlider_3Label             matlab.ui.control.Label
        MaxSlider                    matlab.ui.control.Slider
    end

    
    properties (Access = private)
        imKymo = [] % Original kymograph image
        pathKymo = '';
        nameKymo = '';
        features = [] % lines and curves
        featureInfo % info about features
        poles = []
        polesInfo
        axes_1
        axes_2
        lineClicked = NaN; % line number to finalize
        poleClicked = 0
        keyPressed
        addPole = 0
    end
    
    methods (Access = private, Static = true)
        
        function displayImage( ax, img)
            % display the image nicely in the axes provided
            
            ax.YDir = 'reverse';
            imagesc( ax, mat2gray(img) );
            colormap( ax, gray);
            axis( ax, 'equal');
            xlim( ax, [1 size(img, 2)]);
            ylim( ax, [1 size(img, 1)]);
        end
    end
    
    methods (Access = private)
        
        function ax = replaceUIAxesWithAxes(app, UIAxes)
            % Takes a UIAxes object, and replaces it with an axes object
            
            % Get properties of UIAxes
            props = properties( UIAxes);
            vals = get( UIAxes, props);
            ax = axes(app.UIFigure, 'Visible', 'off');
            for jProp = 1 : length( props)
                try
                    ax.( props{jProp} ) = vals{ jProp};
                end
            end
            ax.Visible = 'on';
            ax.Interactions = [ dataTipInteraction];
            delete(UIAxes)
            
        end
        
        function SimulateClick(app, src)
            
            disp('Simulating Click')
                        
            % get tag of clicked line
            tag = str2double(src.Tag);
            
            % get all tags of all lines
            tagsAll = cellfun( @(x) str2double(x.Tag), app.features);

            lineIdx = find( tag == tagsAll);
            if isempty(lineIdx)
                error('tags werent found correctly')
            end
            
            % update final line property
            app.lineClicked = lineIdx; 
            fprintf( 'Feature %d was clicked\n', lineIdx) 
            app.poleClicked = 0;
            % Update GUI properties
            % set props to line props if available
            % Line Direction
            try
                if strcmp( app.featureInfo.direction{lineIdx}, 'Poleward')
                    app.PolewardButton.Value = 1;
                elseif strcmp( app.featureInfo.direction{lineIdx}, 'Antipoleward')
                    app.AntipolewardButton.Value = 1;
                elseif strcmp( app.featureInfo.direction{lineIdx}, 'Ambiguous')
                    app.AmbiguousButton.Value = 1;
                end
            end
            % Particle Speed
            try
                if strcmp( app.featureInfo.particleSpeed{lineIdx}, 'Slow')
                    app.SlowButton.Value = 1;
                elseif strcmp( app.featureInfo.particleSpeed{lineIdx}, 'Fast')
                    app.FastButton.Value = 1;
                end
            end
            % Amp
            try
                app.MinSlider.Value = app.featureInfo.ampMin{lineIdx};
                app.MaxSlider.Value = app.featureInfo.ampMax{lineIdx};
                img = app.imKymo;
                if ~isempty( img)
                    img( img < app.MinSlider.Value) = app.MinSlider.Value;
                    img( img > app.MaxSlider.Value) = app.MaxSlider.Value;
                    img = mat2gray(img);
                    app.axes_1.Children(end).CData = img;
                    app.axes_2.Children(end).CData = img;
                end
            end
        end
        
        function SimulatePoleClick(app, src)
            
            disp('Simulating Click')
                        
            % get tag of clicked line
            tag = str2double(src.Tag);
            
            % get all tags of all lines
            tagsAll = cellfun( @(x) str2double(x.Tag), app.poles);

            lineIdx = find( tag == tagsAll);
            if isempty(lineIdx)
                error('tags werent found correctly')
            end
            
            % update final line property
            app.lineClicked = lineIdx; 
            fprintf( 'Pole %d was clicked\n', lineIdx) 
            app.poleClicked = 1;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Disable everything except kymograph button
            app.SelectKymographButton.Enable = 1;
            app.CommentsTextArea.Enable = 0;
            app.PolewardButton.Enable = 0;
            app.AntipolewardButton.Enable = 0;
            app.AmbiguousButton.Enable = 0;
            app.LineButton.Enable = 0;
            app.CurveButton.Enable = 0;
            app.FinalizeLineButton.Enable = 0;
            app.DeleteLineButton.Enable = 0;
            app.SaveFeaturesButton.Enable = 0;
            app.MinSlider.Enable = 0;
            app.MaxSlider.Enable = 0;
            app.DrawButton.Enable = 0;
            app.SlowButton.Enable = 0;
            app.FastButton.Enable = 0;
            app.Button.Enable = 0;
            app.ClearButton.Enable = 0;
            app.AddSPBCurveButton.Enable = 0;
            app.AddSPBLineButton.Enable = 0;
            
        end

        % Button pushed function: SelectKymographButton
        function SelectKymographButtonPushed(app, event)
            % Loads selected tiff file, then displays it in both axes in the app    
            
            % change UIAxes to Axes
            app.axes_1 = replaceUIAxesWithAxes(app, app.UIAxes);
            app.axes_2 = replaceUIAxesWithAxes(app, app.UIAxes_2);
            
            app.imKymo = loadTiffImage(); figure( app.UIFigure);
            app.displayImage( app.axes_1, app.imKymo);
            app.displayImage( app.axes_2, app.imKymo);
            app.MinSlider.Limits = double( round( [min(app.imKymo(:)) max(app.imKymo(:)) ] ) );
            app.MinSlider.Value = min( app.MinSlider.Limits);
            app.MaxSlider.Limits = double( round( [min(app.imKymo(:)) max(app.imKymo(:)) ] ) );
            app.MaxSlider.Value = max( app.MinSlider.Limits);
            app.EndFrameEditField.Value = size( app.imKymo, 1);
            app.EndTimesEditField.Value = size( app.imKymo, 1);
            app.TimeStepsEditField.Value = ( app.EndTimesEditField.Value - app.StartTimesEditField.Value ) / ( app.EndFrameEditField.Value - app.StartFrameEditField.Value );
            
            % Enable everything
            app.SelectKymographButton.Enable = 0;
            app.CommentsTextArea.Enable = 1;
            app.PolewardButton.Enable = 1;
            app.AntipolewardButton.Enable = 1;
            app.AmbiguousButton.Enable = 1;
            app.LineButton.Enable = 1;
            app.CurveButton.Enable = 1;
            app.FinalizeLineButton.Enable = 0;
            app.DeleteLineButton.Enable = 0;
            app.MinSlider.Enable = 1;
            app.MaxSlider.Enable = 1;
            app.DrawButton.Enable = 1;
            app.SlowButton.Enable = 1;
            app.FastButton.Enable = 1;
            app.Button.Enable = 1;
            app.ClearButton.Enable = 1;
            app.AddSPBCurveButton.Enable = 1;
            app.AddSPBLineButton.Enable = 1;
            
            function imKymo = loadTiffImage()
                % loads tiff image selected by user
                
                [fname_ext, fpath_only] = uigetfile('.tif', 'Select the kymograph tiff file');
                
                % check to ensure selected file is tif
                [~, fname, fext] = fileparts( fname_ext);
                if ~strcmp( fext, '.tif')
                    error('loadTiffImage: please select .tif file for import');
                end
                
                % now load tiff file as tiff object and then read the image
                tifObj = Tiff( [fpath_only fname_ext]);
                imKymo = read( tifObj);
                imKymo = imKymo(:,:,1);
                app.pathKymo = fpath_only;
                app.nameKymo = fname;
                
            end
            
        end

        % Value changed function: StartFrameEditField
        function StartFrameEditFieldValueChanged(app, event)
            app.TimeStepsEditField.Value = ( app.EndTimesEditField.Value - app.StartTimesEditField.Value ) / ( app.EndFrameEditField.Value - app.StartFrameEditField.Value );
        end

        % Value changed function: EndFrameEditField
        function EndFrameEditFieldValueChanged(app, event)
            app.TimeStepsEditField.Value = ( app.EndTimesEditField.Value - app.StartTimesEditField.Value ) / ( app.EndFrameEditField.Value - app.StartFrameEditField.Value );
        end

        % Value changed function: StartTimesEditField
        function StartTimesEditFieldValueChanged(app, event)
            app.TimeStepsEditField.Value = ( app.EndTimesEditField.Value - app.StartTimesEditField.Value ) / ( app.EndFrameEditField.Value - app.StartFrameEditField.Value );
        end

        % Value changed function: EndTimesEditField
        function EndTimesEditFieldValueChanged(app, event)
            app.TimeStepsEditField.Value = ( app.EndTimesEditField.Value - app.StartTimesEditField.Value ) / ( app.EndFrameEditField.Value - app.StartFrameEditField.Value );
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            valueMin = app.MinSlider.Value;
            valueMax = app.MaxSlider.Value;
            
            if valueMax <= valueMin
                return
            end
            
            % Set the min and max intensity equal to the slider value, apply the
            % threshold to the image in axes and update the display
            
            % original image
            img = app.imKymo;
            
            if ~isempty( img)
                img( img < valueMin) = valueMin;
                img( img > valueMax) = valueMax;
                img = mat2gray(img);
                app.axes_1.Children(end).CData = img;
                app.axes_2.Children(end).CData = img;
            end
            
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            valueMin = app.MinSlider.Value;
            valueMax = app.MaxSlider.Value;
            
            if valueMax <= valueMin
                return
            end                        
            % Set the max brightness equal to the slider value, apply the
            % threshold to the image in axes and update the display
            
            % original image
            img = app.imKymo;
            
            if ~isempty( img)
                img( img < valueMin) = valueMin;
                img( img > valueMax) = valueMax;
                img = mat2gray(img);
                app.axes_1.Children(end).CData = img;
                app.axes_2.Children(end).CData = img;
            end
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            
            % Check what feature to draw by calling on the feature type
            % buttons
            if app.LineButton.Value
                featType = 'Line';
            elseif app.CurveButton.Value
                featType = 'Curve';
            end
            
            % Disable draw button
            app.DrawButton.Enable = 0;
            
            % Disable everything else
            app.CommentsTextArea.Enable = 0;
            app.PolewardButton.Enable = 0;
            app.AntipolewardButton.Enable = 0;
            app.AmbiguousButton.Enable = 0;
            app.LineButton.Enable = 0;
            app.CurveButton.Enable = 0;
            app.FinalizeLineButton.Enable = 0;
            app.DeleteLineButton.Enable = 0;
            app.MinSlider.Enable = 0;
            app.MaxSlider.Enable = 0;
            app.SlowButton.Enable = 0;
            app.FastButton.Enable = 0;
            app.SaveFeaturesButton.Enable = 0;
            c1 = onCleanup( @() cleanIt(app) );
            
            % Make a new image with all the existing lines drawn
            newfig = figure('NumberTitle',"off", 'Name',sprintf('Draw a %s', featType), 'WindowState', 'maximized' );
            ax = axes;
            img = app.imKymo;
            img( img < app.MinSlider.Value) = app.MinSlider.Value;
            img( img > app.MaxSlider.Value) = app.MaxSlider.Value;
            app.displayImage( ax, mat2gray(img)); hold on;
            title('Zoom mode - press Spacebar to enter Draw mode'); set(ax, 'FontSize', 20)
            
            % Plot prior features
            for jfeat = 1 : length( app.features)
                plot( ax, app.features{jfeat}.Position(:,1), app.features{jfeat}.Position(:,2), 'm-', 'LineWidth', 1)
            end
            % Plot pole features if they exist
            for jfeat = 1 : length( app.poles)
                plot( ax, app.poles{jfeat}.Position(:,1), app.poles{jfeat}.Position(:,2), 'b-', 'LineWidth', 2)
            end
            drawnow; zoom( newfig, 'on')
            
            % Book keeping : determine index of prospective new line
            % get tag of last line, and then add 1 to it
            if app.addPole
                jLine = length( app.poles)+1;
                oldIdx = length( app.poles);
                if oldIdx == 0, oldTag = 0; else
                    oldTag = str2num( app.poles{oldIdx}.Tag);
                end
            else
                jLine = length( app.features)+1;
                oldIdx = length( app.features);
                if oldIdx == 0, oldTag = 0; else
                    oldTag = str2num( app.features{oldIdx}.Tag);
                end
            end
            tagNew = oldTag +1;
            
            % Zoom in/ out time mode:
            waitFlag = 1;
            disp('Entered zoom in/out mode')
            while waitFlag
      
                w = waitforbuttonpress;
                if w == 1
                    waitFlag = 0;
                    disp('User pressed a button: end axes interaction mode')
                    zoom( newfig, 'off'); ax.Interactions = [];
                    drawnow; figure( newfig)
                end
                if strcmp( newfig.CurrentCharacter, 'x')
                    waitFlag = 0; disp('Exiting draw mode: user pressed "X".'); close( newfig); figure(app.UIFigure); return;
                end
                pause(0.2)
            end
            
            % Draw Mode : 
            % How to exit this function? waitfor obj to be closed
            timeExit = 60;
            timerStart = tic;
            lineCurrent = [];
            
            % Draw the feature
            disp('Entered draw mode')
            title('Draw mode - press SpaceBar to accept feature' );
            switch featType
                case 'Line'
                    lineCurrent = drawline( ax,'Color', 'r', 'LineWidth', 0.5, 'Alpha', 1);
                case 'Curve'
                    lineCurrent = drawfreehand(ax,'Color', 'r', 'LineWidth', 1, 'Closed', false, 'FaceAlpha', 0, 'Smoothing', 1, 'Alpha', 1);                    
            end
            
            waitFlag = 1;
            while waitFlag

                if toc( timerStart) > timeExit, waitFlag = 0; end
                
                w = waitforbuttonpress;
                if w == 1
                    waitFlag = 0; figure( app.UIFigure); disp('User pressed a button: end draw mode');
                end

            end
            
            % Now exit
            if ~isempty( lineCurrent) && ~isempty(lineCurrent.Position)
            
                % If line was created, move it to the background axis
                lineCurrent.Parent = app.axes_1;
                
                if app.addPole
                    app.poles{jLine} = lineCurrent;
                    app.poles{jLine}.Tag = num2str(tagNew);
                    app.polesInfo.type{ jLine} = featType;
                    app.polesInfo.listeners{jLine} = listener( app.poles{jLine}, 'ROIClicked', @(src,evtData) ListenForClick(src, evtData, app));
                    app.polesInfo.finalized{ jLine} = 0;
                else
                    app.features{jLine} = lineCurrent;
                    app.features{jLine}.Tag = num2str(tagNew);
                    app.featureInfo.type{ jLine} = featType;
                    app.featureInfo.listeners{jLine} = listener( app.features{jLine}, 'ROIClicked', @(src,evtData) ListenForClick(src, evtData, app));
                    app.featureInfo.finalized{ jLine} = 0;
                end
                close( newfig);
                cleanIt(app);
                    
            end
            
            function cleanIt(app)
                
                % Enable everything
                app.DrawButton.Enable = 1;
                app.CommentsTextArea.Enable = 1;
                app.PolewardButton.Enable = 1;
                app.AntipolewardButton.Enable = 1;
                app.FinalizeLineButton.Enable = 1;
                app.DeleteLineButton.Enable = 1;
                app.MinSlider.Enable = 1;
                app.MaxSlider.Enable = 1;
                app.AmbiguousButton.Enable = 1;
                app.LineButton.Enable = 1;
                app.CurveButton.Enable = 1;
                app.SlowButton.Enable = 1;
                app.FastButton.Enable = 1;
                
            end
            
            function ListenForClick(src, evtData, app)
                            
                % get tag of clicked line
                tag = str2double(src.Tag);
                
                % get all tags of all lines
                if app.addPole
                    tagsAll = cellfun( @(x) str2double(x.Tag), app.poles);
                else
                    tagsAll = cellfun( @(x) str2double(x.Tag), app.features);
                end
                lineIdx = find( tag == tagsAll);
                if isempty(lineIdx)
                    error('tags werent found correctly')
                end
                
                % update final line property
                app.lineClicked = lineIdx; 
                disp( sprintf( 'Feature %d was clicked', lineIdx) )
                app.poleClicked = 0;
                
                % Update GUI properties
                % set props to line props if available
                % Line Direction
                try
                    if strcmp( app.featureInfo.direction{lineIdx}, 'Poleward')
                        app.PolewardButton.Value = 1;
                    elseif strcmp( app.featureInfo.direction{lineIdx}, 'Antipoleward')
                        app.AntipolewardButton.Value = 1;
                    elseif strcmp( app.featureInfo.direction{lineIdx}, 'Ambiguous')
                        app.AmbiguousButton.Value = 1;
                    end
                end
                % Particle Speed
                try
                    if strcmp( app.featureInfo.particleSpeed{lineIdx}, 'Slow')
                        app.SlowButton.Value = 1;
                    elseif strcmp( app.featureInfo.particleSpeed{lineIdx}, 'Fast')
                        app.FastButton.Value = 1;
                    end
                end
                % Amp
                try
                    app.MinSlider.Value = app.featureInfo.ampMin{lineIdx};
                    app.MaxSlider.Value = app.featureInfo.ampMax{lineIdx};
                    img = app.imKymo;
                    if ~isempty( img)
                        img( img < app.MinSlider.Value) = app.MinSlider.Value;
                        img( img > app.MaxSlider.Value) = app.MaxSlider.Value;
                        img = mat2gray(img);
                        app.axes_1.Children(end).CData = img;
                        app.axes_2.Children(end).CData = img;
                    end
                end
            end
            
        end

        % Button pushed function: FinalizeLineButton
        function FinalizeLineButtonPushed(app, event)
            % This finalizes the last line user clicked on
            
            idx = app.lineClicked;
            
            % Check if we are finalizing a pole or a feature
            
            if app.poleClicked % POLE
                feats = app.poles;
                featsInfo = app.polesInfo;
                fprintf('Finalizing selected pole %d\n', idx);
                
                app.poles{idx}.Color = [0.2 0.2 0.8];
                app.poles{idx}.InteractionsAllowed = 'none';
                
                % store physical positions and intensity of pole
                pos = app.poles{idx}.Position;
                lenFeat = round( sum( sqrt( sum( diff(pos).^2, 2) ) ) );
                switch app.polesInfo.type{idx}
                    case 'Line'
                        positionVec = linspace( pos(1,1), pos(end,1), lenFeat);
                        timeVec = linspace( pos(1,2), pos(end,2), lenFeat);
                    case 'Curve'
                        positionVec = pos(:,1)';
                        timeVec = pos(:,2)';
                end
                intensityVec = interp2( double(app.imKymo), positionVec, timeVec, 'linear');
                app.polesInfo.timeVec{idx} = timeVec;
                app.polesInfo.posVec{idx} = positionVec;
                app.polesInfo.intVec{idx} = intensityVec;
                app.polesInfo.finalized{idx} = 1;
                
                
            else % FEATURE
                feats = app.features;
                featsInfo = app.featureInfo;
                fprintf('Finalizing selected feature %d\n', idx);
                
                app.features{idx}.Color = 'm';
                app.features{idx}.InteractionsAllowed = 'none';
                
                % Store properties of this line:
                
                % Direction
                try 
                    temp = app.featureInfo.direction{idx};
                    disp('Data exists...')
                catch
                    if app.PolewardButton.Value
                        app.featureInfo.direction{idx} = 'Poleward';
                    elseif app.AntipolewardButton.Value
                        app.featureInfo.direction{idx} = 'Antipoleward';
                    elseif app.AmbiguousButton.Value
                        app.featureInfo.direction{idx} = 'Ambiguous';
                    end
                end
                                
                % Amplitude Limits
                try 
                    temp = app.featureInfo.ampMax{idx};
                    temp = app.featureInfo.ampMin{idx};
                    disp('Data exists...')
                catch
                    app.featureInfo.ampMax{idx} = app.MaxSlider.Value;
                    app.featureInfo.ampMin{idx} = app.MinSlider.Value;
                end
                
                % Particle Speed
                try 
                    temp = app.featureInfo.particleSpeed{idx};
                    disp('Data exists...')
                catch
                    if app.FastButton.Value
                        app.featureInfo.particleSpeed{idx} = 'Fast';
                    elseif app.SlowButton.Value
                        app.featureInfo.particleSpeed{idx} = 'Slow';
                    end
                end
                
                % store physical positions and intensity of feature
                pos = app.features{idx}.Position;
                lenFeat = round( sum( sqrt( sum( diff(pos).^2, 2) ) ) );
                switch app.featureInfo.type{idx}
                    case 'Line'
                        positionVec = linspace( pos(1,1), pos(end,1), lenFeat);
                        timeVec = linspace( pos(1,2), pos(end,2), lenFeat);
                    case 'Curve'
                        positionVec = pos(:,1)';
                        timeVec = pos(:,2)';
                end
                intensityVec = interp2( double(app.imKymo), positionVec, timeVec, 'linear');
                app.featureInfo.timeVec{idx} = timeVec;
                app.featureInfo.posVec{idx} = positionVec;
                app.featureInfo.intVec{idx} = intensityVec;
                app.featureInfo.finalized{idx} = 1;
                
            end
            
            % Check if all lines have been finalized. Then enable stop
            % analysis button
            nLines = length( app.features) + length( app.poles);
            if length(app.poles) == 0
                totFeat = sum( [ app.featureInfo.finalized{:} ] );
            elseif length(app.features) == 0
                totFeat = sum( [ app.polesInfo.finalized{:} ] );
            else
                totFeat = sum( [ app.featureInfo.finalized{:} ] ) + sum( [ app.polesInfo.finalized{:} ] );
            end
            if totFeat == nLines
                app.SaveFeaturesButton.Enable = 1;
            else
                app.SaveFeaturesButton.Enable = 0;
            end

        end

        % Button pushed function: DeleteLineButton
        function DeleteLineButtonPushed(app, event)
            badLine = app.lineClicked;
            
            if app.poleClicked
                app.AddSPBCurveButton.Enable = 1;
                app.AddSPBLineButton.Enable = 1;
                delete( app.poles{ badLine} );
                app.poles( badLine) = [];
                app.polesInfo.type(badLine) = [];
                app.polesInfo.listeners(badLine) = [];
                
            else
                
                delete( app.features{ badLine} );
                app.features( badLine) = [];
                app.featureInfo.type(badLine) = [];
                app.featureInfo.listeners(badLine) = [];
            end
            
            % Check if all lines have been finalized. Then enable stop
            % analysis button
            nLines = length( app.features) + length( app.poles);
            if length(app.poles) == 0
                totFeat = sum( [ app.featureInfo.finalized{:} ] );
            elseif length(app.features) == 0
                totFeat = sum( [ app.polesInfo.finalized{:} ] );
            else
                totFeat = sum( [ app.featureInfo.finalized{:} ] ) + sum( [ app.polesInfo.finalized{:} ] );
            end
            if totFeat == nLines
                app.SaveFeaturesButton.Enable = 1;
            else
                app.SaveFeaturesButton.Enable = 0;
            end
            
        end

        % Button pushed function: SaveFeaturesButton
        function SaveFeaturesButtonPushed(app, event)
            
            % Write data to file
            % open file
            f_write = [ app.pathKymo, app.nameKymo, '_', 'data.txt' ];
            fid = fopen( f_write, 'w');
            
            % Write header information
            writeHeaderData(fid, app);
            
            % Write poles information
            writePolesData( fid, app);
            
            % Write features information
            writeFeatureData( fid, app);
            
            % Close file
            fclose( fid);
            
            % pop up saying features saved
            f = uifigure;
            message = sprintf('Success! \n Kymograph features saved to file: \n %s', f_write);
            uialert(f,message,'Success', 'Icon','success');
            
            function writeHeaderData( fid, app)
                % writes the header information for this kymograph analysis
                
                fprintf( fid, 'KYMOANALYSIS DATA\n\n');
                fprintf( fid, 'General Information :\n\n');

                % Tiff path
                fprintf( fid, 'Tiff path : %s\n', app.pathKymo);
                
                % Spindle type
                if app.MonopolarButton.Value, sType = app.MonopolarButton.Text; elseif app.BipolarButton.Value, sType = app.BipolarButton.Text; else 'undefined'; end
                fprintf( fid, 'Spindle type : %s\n', sType);
                
                % Start and End Frame, Start and End Time, Time Step
                fprintf( fid, 'Start frame : %d\n', app.StartFrameEditField.Value);
                fprintf( fid, 'End frame : %d\n', app.EndFrameEditField.Value);
                fprintf( fid, 'Start time (s) : %4.3f\n', app.StartTimesEditField.Value);
                fprintf( fid, 'End time (s) : %4.3f\n', app.EndTimesEditField.Value);
                fprintf( fid, 'Time step (s) : %4.3f\n', app.TimeStepsEditField.Value);
                
                % Poles Info
                fprintf( fid, 'Num poles : %d\n', length(app.poles) );
                
                % Feature Info
                fprintf( fid, 'Num tracks : %d\n', length(app.features) );
                numPole = sum( strcmp( app.featureInfo.direction, 'Poleward') );
                numApole = sum( strcmp( app.featureInfo.direction, 'Antipoleward') );
                numAmbi = sum( strcmp( app.featureInfo.direction, 'Ambiguous') );
                fprintf( fid, 'Num tracks poleward : %d\n', numPole);
                fprintf( fid, 'Num tracks antipoleward : %d\n', numApole);
                fprintf( fid, 'Num tracks ambiguous : %d\n', numAmbi);
                
                % Write Comments
                fprintf( fid, 'Comments : {\n');
                for jText = 1 : size( app.CommentsTextArea.Value, 1)
                    fprintf( fid, [ app.CommentsTextArea.Value{jText, 1} '\n']);
                end
                fprintf( fid, '}\n');

            end
        
            function writePolesData( fid, app)
                % write data about poles
                
                fprintf( fid, 'Poles Information :\n\n\n');
                
                for jFeat = 1 : length( app.poles)
                    
                    % Pole number
                    fprintf( fid, 'Pole number : %d\n\n', jFeat);
                    
                    % Times (s)
                    fprintf( fid, 'Time (s) : %s\n\n', regexprep( num2str( app.polesInfo.timeVec{jFeat} .* app.TimeStepsEditField.Value ),'\s+',',') );
                    
                    % Position (microns)
                    fprintf( fid, 'Position (um) : %s\n\n', regexprep( num2str( app.polesInfo.posVec{jFeat} .* app.XPixelSizeumEditField.Value ),'\s+',',') );
                    
                    % Intensity 
                    fprintf( fid, 'Intensity : %s\n\n', regexprep( num2str( app.polesInfo.intVec{jFeat}),'\s+',',') );
                    
                    % Time Pixels
                    fprintf( fid, 'Time pixel : %s\n\n', regexprep( num2str( app.polesInfo.timeVec{jFeat}),'\s+',',') );
                    
                    % Position Pixels
                    fprintf( fid, 'Position pixel : %s\n\n', regexprep( num2str( app.polesInfo.posVec{jFeat}),'\s+',',') );
                    
                    % Feature Type
                    fprintf( fid, 'Pole type : %s\n', app.polesInfo.type{jFeat});

                    % Start and End Frame, Start and End Time, Time Step
                    fprintf( fid, 'Start frame : %d\n', app.StartFrameEditField.Value);
                    fprintf( fid, 'End frame : %d\n', app.EndFrameEditField.Value);
                    fprintf( fid, 'Start time (s) : %4.3f\n', app.StartTimesEditField.Value);
                    fprintf( fid, 'End time (s) : %4.3f\n', app.EndTimesEditField.Value);
                    fprintf( fid, 'Time step (s) : %4.3f\n', app.TimeStepsEditField.Value);
                    
                    fprintf( fid, '\n');
                    
                end
            
            end
        
            function writeFeatureData( fid, app)
                % write data about features
                
                fprintf( fid, 'Feature Information :\n\n\n');
                
                for jFeat = 1 : length( app.features)
                    
                    % Feature number
                    fprintf( fid, 'Feature number : %d\n\n', jFeat);
                    
                    % Times (s)
                    fprintf( fid, 'Time (s) : %s\n\n', regexprep( num2str( app.featureInfo.timeVec{jFeat} .* app.TimeStepsEditField.Value ),'\s+',',') );
                    
                    % Position (microns)
                    fprintf( fid, 'Position (um) : %s\n\n', regexprep( num2str( app.featureInfo.posVec{jFeat} .* app.XPixelSizeumEditField.Value ),'\s+',',') );
                    
                    % Intensity 
                    fprintf( fid, 'Intensity : %s\n\n', regexprep( num2str( app.featureInfo.intVec{jFeat}),'\s+',',') );
                    
                    % Time Pixels
                    fprintf( fid, 'Time pixel : %s\n\n', regexprep( num2str( app.featureInfo.timeVec{jFeat}),'\s+',',') );
                    
                    % Position Pixels
                    fprintf( fid, 'Position pixel : %s\n\n', regexprep( num2str( app.featureInfo.posVec{jFeat}),'\s+',',') );

                    % Brightness (Max and Min)
                    fprintf( fid, 'Brightness max : %5.2f\n', app.featureInfo.ampMax{jFeat});
                    fprintf( fid, 'Brightness min : %5.2f\n', app.featureInfo.ampMin{jFeat});
                    
                    % Feature Direction
                    fprintf( fid, 'Feature direction : %s\n', app.featureInfo.direction{jFeat});
                    
                    % Feature Speed
                    fprintf( fid, 'Feature speed : %s\n', app.featureInfo.particleSpeed{jFeat});
                    
                    % Feature Type
                    fprintf( fid, 'Feature type : %s\n', app.featureInfo.type{jFeat});

                    % Start and End Frame, Start and End Time, Time Step
                    fprintf( fid, 'Start frame : %d\n', app.StartFrameEditField.Value);
                    fprintf( fid, 'End frame : %d\n', app.EndFrameEditField.Value);
                    fprintf( fid, 'Start time (s) : %4.3f\n', app.StartTimesEditField.Value);
                    fprintf( fid, 'End time (s) : %4.3f\n', app.EndTimesEditField.Value);
                    fprintf( fid, 'Time step (s) : %4.3f\n', app.TimeStepsEditField.Value);
                    
                    fprintf( fid, '\n');
                    
                end
            
            end
            
        end

        % Button pushed function: Button
        function ImportButtonPushed(app, event)
            % import line information from a .txt file
            
            % prompt the user to select the .txt file
            [txtnameext, txtpath] = uigetfile([app.pathKymo, filesep,'.txt'], 'Select the .txt file with lines to import.');
                
            % check to ensure selected file is tif
            [~, fname, fext] = fileparts( txtnameext);
            if ~strcmp( fext, '.txt')
                error('please select .txt file for import of lines');
            end
            figure(app.UIFigure)
            
            % Read the file
            % Set all the values, and the comments, then loop over the
            % features and simulate draw them as imagesROI objects and add
            % them to app.features and app.featureInfo as non-finalized
            % features
            fid = fopen( [txtpath,txtnameext], 'r');
            
            % read all file lines
            fdata = {};
            while ~feof(fid)
                fdata = { fdata{:} fgetl(fid) };
            end
            
            % Close file
            fclose( fid);
            
            % Write header information
            readHeaderData(fdata, app);
            
            % Write poles information
            readPolesData( fdata, app); 
            
            % Write features information
            readFeatureData( fdata, app);     
            
            function readHeaderData(fdata, app)
                % writes the header information for this kymograph analysis
                
                % find upper lower indices for general info data
                ginfo(1) = 1;
                ginfo(2) = find( cellfun( @(x) any( strfind(x, 'Poles Information') ), fdata ) , 1, 'first');
                gdata = fdata(ginfo(1) : ginfo(2)-1);
                
                % Spindle Type
                spindleTypeIdx = find( cellfun( @(x) any( strfind(x, 'Spindle type : ') ), gdata ) , 1, 'first');
                spindleType = erase( gdata(spindleTypeIdx), 'Spindle type : ');
                if strcmpi( spindleType, 'Bipolar')
                    app.BipolarButton.Value = 1;
                elseif strcmpi( spindleType, 'Monopolar')
                    app.MonopolarButton.Value = 1;
                else
                    error('unknown spindle type')
                end
                
                % Start and End Frame, Start and End Time, Time Step
                startFrameIdx = find( cellfun( @(x) any( strfind(x, 'Start frame : ') ), gdata ) , 1, 'first');
                app.StartFrameEditField.Value = str2double( erase( gdata(startFrameIdx), 'Start frame : ') );
                app.EndFrameEditField.Value = str2double( erase( gdata(startFrameIdx+1), 'End frame : ') );
                app.StartTimesEditField.Value = str2double( erase( gdata(startFrameIdx+2), 'Start time (s) : ') );
                app.EndTimesEditField.Value = str2double( erase( gdata(startFrameIdx+3), 'End time (s) : ') );
                app.TimeStepsEditField.Value = str2double( erase( gdata(startFrameIdx+4), 'Time step (s) : ') );
                
                % Comments
                CommentsIdx = find( cellfun( @(x) any( strfind(x, 'Comments : ') ), gdata ) , 1, 'first');
                comIdx = CommentsIdx+1 : ginfo(2)-2;
                for jText = 1:length(comIdx)
                    liner = gdata{comIdx(jText)};
                    app.CommentsTextArea.Value{jText, 1} = liner;
                end

            end
            
            function readPolesData( fdata, app)
                % read data about poles
                
                % find number of poles
                numTracksIdx = find( cellfun( @(x) any( strfind(x, 'Num poles : ') ), fdata) , 1, 'first');
                numFeat = str2double( erase( fdata(numTracksIdx), 'Num poles : ') );
                
                for jFeat = 1 : numFeat
                    
                    % find start idx
                    startIdx = find( cellfun( @(x) any( strfind(x, ['Pole number : ' num2str(jFeat)]) ), fdata ) , 1, 'first');
                    if jFeat ~= numFeat
                        endIdx = find( cellfun( @(x) any( strfind(x, ['Pole number : ' num2str(jFeat+1)]) ), fdata ) , 1, 'first')-1;
                    else
                        endIdx = find( cellfun( @(x) any( strfind(x, 'Feature Information' ) ), fdata ) , 1, 'first');
                    end
                    
                    featdata = fdata(startIdx:endIdx);
                    
                    % get times
                    timesIdx = find( cellfun( @(x) any( strfind(x, 'Time pixel : ') ), featdata ) , 1, 'first');
                    timesCell = erase( featdata(timesIdx), 'Time pixel : ');
                    times = str2num( timesCell{:} );
                    
                    % get position
                    posIdx = find( cellfun( @(x) any( strfind(x, 'Position pixel : ') ), featdata ) , 1, 'first');
                    posCell = erase( featdata(posIdx), 'Position pixel : ');
                    pos = str2num( posCell{:} );
                    
                    % Feature Type
                    typeIdx = find( cellfun( @(x) any( strfind(x, 'Pole type : ') ), featdata ) , 1, 'first');
                    typeCell = erase( featdata(typeIdx), 'Pole type : ');
                    type = typeCell{:};
                    
                    % Add this feature
                    cFeat = length( app.poles);
                    if cFeat==0
                        maxTag = 0; 
                    else 
                        maxTag = max( cellfun( @(x) str2double(x.Tag), app.poles) ); 
                    end
                    
                    switch type
                        case 'Line'
                            app.poles{cFeat+1} = drawline( app.axes_1,'Color', 'b', 'LineWidth', 0.5, 'Alpha', 1, 'Position', [pos(1), times(1) ; pos(end), times(end)]);
                        case 'Curve'
                            app.poles{cFeat+1} = drawfreehand(app.axes_1,'Color', 'b', 'LineWidth', 1, 'Closed', false, 'FaceAlpha', 0, 'Smoothing', 1, 'Alpha', 1, 'Position', [pos', times']);
                    end
                    app.poles{cFeat+1}.Tag = num2str( maxTag+1);
                    app.polesInfo.type{ cFeat+1} = type;
                    app.polesInfo.finalized{cFeat+1} = 0;
                    app.polesInfo.listeners{cFeat+1} = listener( app.poles{cFeat+1}, 'ROIClicked', @(src,evtData) ListenForClick(src, evtData, app));
                    
                end
                
                function ListenForClick(src, evtData, app)
                            
                    % get tag of clicked line
                    tag = str2double(src.Tag);
                    
                    % get all tags of all lines
                    tagsAll = cellfun( @(x) str2double(x.Tag), app.poles);
    
                    lineIdx = find( tag == tagsAll);
                    if isempty(lineIdx)
                        error('tags werent found correctly')
                    end
                    
                    % update final line property
                    app.lineClicked = lineIdx; 
                    fprintf( 'Pole %d was clicked\n', lineIdx)
                    app.poleClicked = 1;
                
                end
                
            end
            
            function readFeatureData( fdata, app)
                % write data about features
                
                % find number of features
                numTracksIdx = find( cellfun( @(x) any( strfind(x, 'Num tracks : ') ), fdata) , 1, 'first');
                numFeat = str2double( erase( fdata(numTracksIdx), 'Num tracks : ') );
                
                for jFeat = 1 : numFeat
                    
                    % find start idx
                    startIdx = find( cellfun( @(x) any( strfind(x, ['Feature number : ' num2str(jFeat)]) ), fdata ) , 1, 'first');
                    if jFeat ~= numFeat
                        endIdx = find( cellfun( @(x) any( strfind(x, ['Feature number : ' num2str(jFeat+1)]) ), fdata ) , 1, 'first')-1;
                    else
                        endIdx = length( fdata);
                    end
                    
                    featdata = fdata(startIdx:endIdx);
                    
                    % get times
                    timesIdx = find( cellfun( @(x) any( strfind(x, 'Time pixel : ') ), featdata ) , 1, 'first');
                    timesCell = erase( featdata(timesIdx), 'Time pixel : ');
                    times = str2num( timesCell{:} );
                    
                    % get position
                    posIdx = find( cellfun( @(x) any( strfind(x, 'Position pixel : ') ), featdata ) , 1, 'first');
                    posCell = erase( featdata(posIdx), 'Position pixel : ');
                    pos = str2num( posCell{:} );

                    % Brightness (Max and Min)
                    ampMaxIdx = find( cellfun( @(x) any( strfind(x, 'Brightness max : ') ), featdata ) , 1, 'first');
                    ampCell = erase( featdata(ampMaxIdx), 'Brightness max : ');
                    ampMax = str2num( ampCell{:} );
                    
                    ampMinIdx = find( cellfun( @(x) any( strfind(x, 'Brightness min : ') ), featdata ) , 1, 'first');
                    ampCell = erase( featdata(ampMinIdx), 'Brightness min : ');
                    ampMin = str2num( ampCell{:} );
                    
                    % Feature Direction
                    dirIdx = find( cellfun( @(x) any( strfind(x, 'Feature direction : ') ), featdata ) , 1, 'first');
                    dirCell = erase( featdata(dirIdx), 'Feature direction : ');
                    dir = dirCell{:};
                    
                    % Feature Speed
                    speedIdx = find( cellfun( @(x) any( strfind(x, 'Feature speed : ') ), featdata ) , 1, 'first');
                    if ~isempty(speedIdx)
                        speedCell = erase( featdata(speedIdx), 'Feature speed : ');
                        speed = speedCell{:};
                    end
                    
                    % Feature Type
                    typeIdx = find( cellfun( @(x) any( strfind(x, 'Feature type : ') ), featdata ) , 1, 'first');
                    typeCell = erase( featdata(typeIdx), 'Feature type : ');
                    type = typeCell{:};
                    
                    % Add this feature
                    cFeat = length( app.features);
                    if cFeat==0
                        maxTag = 0; 
                    else 
                        maxTag = max( cellfun( @(x) str2double(x.Tag), app.features) ); 
                    end
                    
                    switch type
                        case 'Line'
                            app.features{cFeat+1} = drawline( app.axes_1,'Color', 'r', 'LineWidth', 0.5, 'Alpha', 1, 'Position', [pos(1), times(1) ; pos(end), times(end)]);
                        case 'Curve'
                            app.features{cFeat+1} = drawfreehand(app.axes_1,'Color', 'r', 'LineWidth', 1, 'Closed', false, 'FaceAlpha', 0, 'Smoothing', 1, 'Alpha', 1, 'Position', [pos', times']);
                    end
                    app.features{cFeat+1}.Tag = num2str( maxTag+1);
                    app.featureInfo.type{ cFeat+1} = type;
                    app.featureInfo.ampMax{cFeat+1} = ampMax;
                    app.featureInfo.ampMin{cFeat+1} = ampMin;
                    app.featureInfo.direction{cFeat+1} = dir;
                    app.featureInfo.finalized{cFeat+1} = 0;
                    try
                        app.featureInfo.particleSpeed{cFeat+1} = speed;
                    end
                    app.featureInfo.listeners{cFeat+1} = listener( app.features{cFeat+1}, 'ROIClicked', @(src,evtData) ListenForClick(src, evtData, app));
                    
                end
                
                function ListenForClick(src, evtData, app)
                            
                    % get tag of clicked line
                    tag = str2double(src.Tag);
                    
                    % get all tags of all lines
                    tagsAll = cellfun( @(x) str2double(x.Tag), app.features);
    
                    lineIdx = find( tag == tagsAll);
                    if isempty(lineIdx)
                        error('tags werent found correctly')
                    end
                    
                    % update final line property
                    app.lineClicked = lineIdx;
                    disp( sprintf( 'Feature %d was clicked', lineIdx) )
                    app.poleClicked = 0;

                    % set props to line props if available
                    % Line Direction
                    try
                        if strcmp( app.featureInfo.direction{lineIdx}, 'Poleward')
                            app.PolewardButton.Value = 1;
                        elseif strcmp( app.featureInfo.direction{lineIdx}, 'Antipoleward')
                            app.AntipolewardButton.Value = 1;
                        elseif strcmp( app.featureInfo.direction{lineIdx}, 'Ambiguous')
                            app.AmbiguousButton.Value = 1;
                        end
                    end
                    % Particle Speed
                    try
                        if strcmp( app.featureInfo.particleSpeed{lineIdx}, 'Slow')
                            app.SlowButton.Value = 1;
                        elseif strcmp( app.featureInfo.particleSpeed{lineIdx}, 'Fast')
                            app.FastButton.Value = 1;
                        end
                    end
                    % Amp
                    try
                        app.MinSlider.Value = app.featureInfo.ampMin{lineIdx};
                        app.MaxSlider.Value = app.featureInfo.ampMax{lineIdx};
                        img = app.imKymo;
                        if ~isempty( img)
                            img( img < app.MinSlider.Value) = app.MinSlider.Value;
                            img( img > app.MaxSlider.Value) = app.MaxSlider.Value;
                            img = mat2gray(img);
                            app.axes_1.Children(end).CData = img;
                            app.axes_2.Children(end).CData = img;
                        end
                    end
            
                end
            
            end

            % Enable everything
            app.DrawButton.Enable = 1;
            app.CommentsTextArea.Enable = 1;
            app.PolewardButton.Enable = 1;
            app.AntipolewardButton.Enable = 1;
            app.FinalizeLineButton.Enable = 1;
            app.DeleteLineButton.Enable = 1;
            app.MinSlider.Enable = 1;
            app.MaxSlider.Enable = 1;
            app.AmbiguousButton.Enable = 1;
            app.LineButton.Enable = 1;
            app.CurveButton.Enable = 1;
            app.SlowButton.Enable = 1;
            app.FastButton.Enable = 1;
            
        end

        % Button pushed function: FinalizeAllButton
        function FinalizeAllButtonPushed(app, event)
            
            % For each feature, simulate a click and then finalize that
            % feature
            for jFeat = 1: length( app.features)
                
                % Simulate a click
                app.SimulateClick( app.features{jFeat})
                
                % Finalize it
                app.FinalizeLineButtonPushed([])
            end
            
            % For each pole, simulate a click and then finalize that
            % pole
            for jFeat = 1: length( app.poles)
                
                % Simulate a click
                app.SimulatePoleClick( app.poles{jFeat})
                
                % Finalize it
                app.FinalizeLineButtonPushed([])
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % clear all features
            
            % delete all lines
            for jFeat = 1 : length( app.features)
                delete( app.features{jFeat})
            end
            for jFeat = 1 : length( app.poles)
                delete( app.poles{jFeat})
            end
            app.features = [];
            app.featureInfo = [];
            app.poles = [];
            app.polesInfo = [];
            app.SaveFeaturesButton.Enable = 0;
            app.FinalizeLineButton.Enable = 0;
            app.DeleteLineButton.Enable = 0;
        end

        % Button pushed function: AddSPBLineButton
        function AddSPBLineButtonPushed(app, event)
            
            app.AddSPBLineButton.Enable = 0;
            app.AddSPBCurveButton.Enable = 0;
            % Check how many poles currently exist
            numPoles = length( app.poles);
            if numPoles > 1
                return
            end
            
            % Simulate Draw Line Click
            app.LineButton.Value = 1;
            app.addPole = 1;
            app.DrawButtonPushed([]);
            app.addPole = 0;
            app.poles{numPoles+1}.Color = [0.4 0.4 1.0];
            
            app.polesInfo.listeners{ numPoles+1} = listener( app.poles{ numPoles+1}, 'ROIClicked', @(src,evtData) ListenForClick(src, evtData, app));
            app.AddSPBLineButton.Enable = 1;
            app.AddSPBCurveButton.Enable = 1;
            
            if length(app.poles) == 2
                app.AddSPBCurveButton.Enable = 0;
                app.AddSPBLineButton.Enable = 0;
            end
            
            function ListenForClick(src, evtData, app)
                            
                % get tag of clicked line
                tag = str2double(src.Tag);
                
                % get all tags of all lines
                tagsAll = cellfun( @(x) str2double(x.Tag), app.poles);

                lineIdx = find( tag == tagsAll);
                if isempty(lineIdx)
                    error('tags werent found correctly')
                end
                
                % update final line property
                app.lineClicked = lineIdx; 
                fprintf( 'Pole %d was clicked\n', lineIdx) 
                app.poleClicked = 1;
            end
            
        end

        % Button pushed function: AddSPBCurveButton
        function AddSPBCurveButtonPushed(app, event)
            
            app.AddSPBCurveButton.Enable = 0;
            app.AddSPBLineButton.Enable = 0;
            
            % Check how many poles currently exist
            numPoles = length( app.poles);
            if numPoles > 1
                return
            end
            
            % Simulate Draw Line Click
            app.CurveButton.Value = 1;
            app.addPole = 1;
            app.DrawButtonPushed([]);
            app.addPole = 0;
            app.poles{numPoles+1}.Color = [0.4 0.4 1.0];
            
            app.polesInfo.listeners{ numPoles+1} = listener( app.poles{ numPoles+1}, 'ROIClicked', @(src,evtData) ListenForClick(src, evtData, app));
            app.AddSPBCurveButton.Enable = 1;
            app.AddSPBLineButton.Enable = 1;
            
            if length(app.poles) == 2
                app.AddSPBCurveButton.Enable = 0;
                app.AddSPBLineButton.Enable = 0;
            end
            
            function ListenForClick(src, evtData, app)
                            
                % get tag of clicked line
                tag = str2double(src.Tag);
                
                % get all tags of all lines
                tagsAll = cellfun( @(x) str2double(x.Tag), app.poles);

                lineIdx = find( tag == tagsAll);
                if isempty(lineIdx)
                    error('tags werent found correctly')
                end
                
                % update final line property
                app.lineClicked = lineIdx; 
                fprintf( 'Pole %d was clicked\n', lineIdx) 
                app.poleClicked = 1;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Kymo Lines')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Reference')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.KymographAnalysisToolLabel = uilabel(app.UIFigure);
            app.KymographAnalysisToolLabel.Text = 'Kymograph Analysis Tool';
            app.InitializePanel = uipanel(app.UIFigure);
            app.InitializePanel.Title = 'Initialize';
            app.SelectKymographButton = uibutton(app.InitializePanel, 'push');
            app.SelectKymographButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKymographButtonPushed, true);
            app.SelectKymographButton.Text = 'Select Kymograph';
            app.StartFrameEditFieldLabel = uilabel(app.InitializePanel);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.InitializePanel, 'numeric');
            app.StartFrameEditField.Limits = [0 Inf];
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrameEditFieldValueChanged, true);
            app.StartFrameEditField.Value = 1;
            app.TimeStepsEditField_4Label = uilabel(app.InitializePanel);
            app.TimeStepsEditField_4Label.Text = 'Time-Step (s)';
            app.TimeStepsEditField = uieditfield(app.InitializePanel, 'numeric');
            app.TimeStepsEditField.Limits = [0 Inf];
            app.TimeStepsEditField.Editable = 'off';
            app.TimeStepsEditField.Value = 1;
            app.EndFrameEditFieldLabel = uilabel(app.InitializePanel);
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.InitializePanel, 'numeric');
            app.EndFrameEditField.Limits = [0 Inf];
            app.EndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrameEditFieldValueChanged, true);
            app.EndFrameEditField.Value = 1;
            app.StartTimesEditFieldLabel = uilabel(app.InitializePanel);
            app.StartTimesEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimesEditField = uieditfield(app.InitializePanel, 'numeric');
            app.StartTimesEditField.Limits = [0 Inf];
            app.StartTimesEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimesEditFieldValueChanged, true);
            app.EndTimesEditFieldLabel = uilabel(app.InitializePanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.InitializePanel, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EndTimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditFieldValueChanged, true);
            app.EndTimesEditField.Value = 1;
            app.SpindleTypeButtonGroup = uibuttongroup(app.InitializePanel);
            app.SpindleTypeButtonGroup.Title = 'Spindle Type';
            app.MonopolarButton = uiradiobutton(app.SpindleTypeButtonGroup);
            app.MonopolarButton.Text = 'Monopolar';
            app.MonopolarButton.Value = true;
            app.BipolarButton = uiradiobutton(app.SpindleTypeButtonGroup);
            app.BipolarButton.Text = 'Bipolar';
            app.CommentsTextAreaLabel = uilabel(app.InitializePanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.InitializePanel);
            app.XPixelSizeumEditFieldLabel = uilabel(app.InitializePanel);
            app.XPixelSizeumEditFieldLabel.Text = 'X Pixel Size (um)';
            app.XPixelSizeumEditField = uieditfield(app.InitializePanel, 'numeric');
            app.XPixelSizeumEditField.Limits = [0 Inf];
            app.XPixelSizeumEditField.Value = 0.1067;
            app.AddSPBLineButton = uibutton(app.InitializePanel, 'push');
            app.AddSPBLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddSPBLineButtonPushed, true);
            app.AddSPBLineButton.Text = 'Add SPB Line';
            app.AddSPBCurveButton = uibutton(app.InitializePanel, 'push');
            app.AddSPBCurveButton.ButtonPushedFcn = createCallbackFcn(app, @AddSPBCurveButtonPushed, true);
            app.AddSPBCurveButton.Text = 'Add SPB Curve';
            app.ProcessPanel = uipanel(app.UIFigure);
            app.ProcessPanel.Title = 'Process';
            app.LineDirectionButtonGroup = uibuttongroup(app.ProcessPanel);
            app.LineDirectionButtonGroup.Title = 'Line Direction';
            app.PolewardButton = uiradiobutton(app.LineDirectionButtonGroup);
            app.PolewardButton.Text = 'Poleward';
            app.PolewardButton.Value = true;
            app.AntipolewardButton = uiradiobutton(app.LineDirectionButtonGroup);
            app.AntipolewardButton.Text = 'Anti-poleward';
            app.AmbiguousButton = uiradiobutton(app.LineDirectionButtonGroup);
            app.AmbiguousButton.Text = 'Ambiguous';
            app.FinalizeLineButton = uibutton(app.ProcessPanel, 'push');
            app.FinalizeLineButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeLineButtonPushed, true);
            app.FinalizeLineButton.Text = {'Finalize'; 'Line'};
            app.DeleteLineButton = uibutton(app.ProcessPanel, 'push');
            app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
            app.DeleteLineButton.Text = 'Delete Line';
            app.SaveFeaturesButton = uibutton(app.ProcessPanel, 'push');
            app.SaveFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFeaturesButtonPushed, true);
            app.SaveFeaturesButton.Text = 'Save Features';
            app.SelectFeatureButtonGroup = uibuttongroup(app.ProcessPanel);
            app.SelectFeatureButtonGroup.Title = 'Select Feature';
            app.LineButton = uitogglebutton(app.SelectFeatureButtonGroup);
            app.LineButton.Text = 'Line';
            app.LineButton.Value = true;
            app.CurveButton = uitogglebutton(app.SelectFeatureButtonGroup);
            app.CurveButton.Text = 'Curve';
            app.DrawButton = uibutton(app.ProcessPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ParticleVelocityButtonGroup = uibuttongroup(app.ProcessPanel);
            app.ParticleVelocityButtonGroup.Title = 'Particle Velocity';
            app.SlowButton = uiradiobutton(app.ParticleVelocityButtonGroup);
            app.SlowButton.Text = 'Slow';
            app.SlowButton.Value = true;
            app.FastButton = uiradiobutton(app.ParticleVelocityButtonGroup);
            app.FastButton.Text = 'Fast';
            app.Button = uibutton(app.ProcessPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.Button.Icon = 'icon_import.png';
            app.Button.Text = '';
            app.ClearButton = uibutton(app.ProcessPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FinalizeAllButton = uibutton(app.ProcessPanel, 'push');
            app.FinalizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeAllButtonPushed, true);
            app.FinalizeAllButton.Text = {'Finalize'; 'All'};
            app.ImageAdjustmentPanel = uipanel(app.UIFigure);
            app.ImageAdjustmentPanel.Title = 'Image Adjustment';
            app.MinSliderLabel = uilabel(app.ImageAdjustmentPanel);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.ImageAdjustmentPanel);
            app.MinSlider.Limits = [0 2000];
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MinSlider.Value = 800;
            app.MaxSlider_3Label = uilabel(app.ImageAdjustmentPanel);
            app.MaxSlider_3Label.Text = 'Max';
            app.MaxSlider = uislider(app.ImageAdjustmentPanel);
            app.MaxSlider.Limits = [0 2000];
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.Value = 800;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KymoAnnotate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end